classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        TabGroup       matlab.ui.container.TabGroup
        Tab            matlab.ui.container.Tab
        Label_2        matlab.ui.control.Label
        Image          matlab.ui.control.Image
        Button_6       matlab.ui.control.Button
        Panel          matlab.ui.container.Panel
        ButtonGroup_3  matlab.ui.container.ButtonGroup
        Button_19      matlab.ui.control.RadioButton
        Button_18      matlab.ui.control.RadioButton
        Button_17      matlab.ui.control.RadioButton
        Button_16      matlab.ui.control.RadioButton
        Label_3        matlab.ui.control.Label
        Slider         matlab.ui.control.Slider
        Label          matlab.ui.control.Label
        ButtonGroup_2  matlab.ui.container.ButtonGroup
        Button_8       matlab.ui.control.RadioButton
        Button_7       matlab.ui.control.RadioButton
        ButtonGroup    matlab.ui.container.ButtonGroup
        Button_5       matlab.ui.control.RadioButton
        Button_4       matlab.ui.control.RadioButton
        Button_3       matlab.ui.control.RadioButton
        Button_2       matlab.ui.control.RadioButton
        CheckBox_2     matlab.ui.control.CheckBox
        Button         matlab.ui.control.Button
        UIAxes         matlab.ui.control.UIAxes
        Tab_4          matlab.ui.container.Tab
        Button_9       matlab.ui.control.Button
        Image2         matlab.ui.control.Image
        Label_4        matlab.ui.control.Label
        Tab_2          matlab.ui.container.Tab
        UITable        matlab.ui.control.Table
        Button_14      matlab.ui.control.Button
        Label_8        matlab.ui.control.Label
        Button_13      matlab.ui.control.Button
        Label_7        matlab.ui.control.Label
        Button_12      matlab.ui.control.Button
        Label_6        matlab.ui.control.Label
        Button_11      matlab.ui.control.Button
        Button_10      matlab.ui.control.Button
        Label_5        matlab.ui.control.Label
        UIAxes4_2      matlab.ui.control.UIAxes
        UIAxes4        matlab.ui.control.UIAxes
        UIAxes2_3      matlab.ui.control.UIAxes
        UIAxes2_2      matlab.ui.control.UIAxes
        UIAxes2        matlab.ui.control.UIAxes
        Tab_3          matlab.ui.container.Tab
        Button_15      matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)

            if app.Button_7.Value == true
                name = 'DFH';
            else
                name = 'DFHSlice';
            end

            if app.Button_16.Value == true
                env = 'none';
            elseif app.Button_17.Value == true
                env = 'linear';
            elseif app.Button_18.Value == true
                env = 'exp';
            elseif app.Button_19.Value == true
                env = 'guitar';
            end

            if app.CheckBox_2 == true
                harmonic = true;
            else
                harmonic = false;
            end

            if app.Button_2.Value == true
                tune = 'Higher8';
            elseif app.Button_3.Value == true
                tune = 'Lower8';
            elseif app.Button_4.Value == true
                tune = 'HigherHalf';
            else
                tune = 'Norm';
            end

            T = app.Slider.Value;
            if T < 0.375
                T = 1/4;
            elseif T < 0.625
                T = 1/2;
            elseif T < 0.875
                T = 3/4;
            else
                T = 1;
            end
            
            episode = getEpisode(name);
            DFH = generateMusic(episode, 8000, T, env, tune, harmonic);
            
            dt = 1/8000;
            plot(app.UIAxes, 0:dt:dt*length(DFH)-dt, DFH);
            sound(DFH);
            
            
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            clear sound;        % 停止播放
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            exp_5;
        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
            exp_6;
        end

        % Button pushed function: Button_11
        function Button_11Pushed(app, event)
            clear sound;        % 停止播放
        end

        % Button pushed function: Button_12
        function Button_12Pushed(app, event)
            load("Guitar.MAT");
            f_s = 8000;
            dt = 1/f_s;
            idx = 0:dt:dt*length(realwave)-dt;
            
            plot(app.UIAxes2, idx, realwave, 'red');
            plot(app.UIAxes2_2, idx, wave2proc, 'blue');
            
            newwave = resample(realwave, 250, 243);     % resample to 250, each period 25
            onePeriod = zeros(25, 1);
            for m = 1:25
                for n = 0:9
                    onePeriod(m) = onePeriod(m) + newwave(m + n*25);
                end
                onePeriod(m) = onePeriod(m)/10;
            end
            tenPeriod = repmat(onePeriod, 10, 1);
            finalResult = resample(tenPeriod, 243, 250);
            
            plot(app.UIAxes2_3, idx, finalResult, 'green');
        end

        % Button pushed function: Button_13
        function Button_13Pushed(app, event)
            [y, fourier, f] = getHarmonic();

            plot(app.UIAxes4, f, fourier);     
            bar(app.UIAxes4_2, y);
        end

        % Button pushed function: Button_14
        function Button_14Pushed(app, event)
            h = msgbox('分析中，请稍候……','提示');
            realWave = audioread("fmt.wav");      % wavread函数已删除

            [y, f, t] = getFreqAndDistri(realWave, 'y');
            rhythm = getRhythm();
            delete(h);
            msgbox('分析成功！', '提示');
            set(app.UITable, 'Data', [f;rhythm;t]);
        end

        % Button pushed function: Button_15
        function Button_15Pushed(app, event)
            h = msgbox('分析中，请稍候……','提示');
            exp_11;
        end

        % Selection changed function: ButtonGroup_3
        function ButtonGroup_3SelectionChanged(app, event)
            selectedButton = app.ButtonGroup_3.SelectedObject;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '音乐合成——东方红';
            app.UIAxes = uiaxes(app.Tab);
            title(app.UIAxes, '音乐波形')
            xlabel(app.UIAxes, 't')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Button = uibutton(app.Tab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '播放';
            app.Panel = uipanel(app.Tab);
            app.Panel.Title = '操作面板';
            app.CheckBox_2 = uicheckbox(app.Panel);
            app.CheckBox_2.Text = '添加谐波';
            app.ButtonGroup = uibuttongroup(app.Panel);
            app.ButtonGroup.Title = '音调';
            app.Button_2 = uiradiobutton(app.ButtonGroup);
            app.Button_2.Text = '升高八度';
            app.Button_3 = uiradiobutton(app.ButtonGroup);
            app.Button_3.Text = '降低八度';
            app.Button_4 = uiradiobutton(app.ButtonGroup);
            app.Button_4.Text = '升高半个音阶';
            app.Button_5 = uiradiobutton(app.ButtonGroup);
            app.Button_5.Text = '原调';
            app.Button_5.Value = true;
            app.ButtonGroup_2 = uibuttongroup(app.Panel);
            app.ButtonGroup_2.Title = '歌曲';
            app.Button_7 = uiradiobutton(app.ButtonGroup_2);
            app.Button_7.Text = '完整版';
            app.Button_7.Value = true;
            app.Button_8 = uiradiobutton(app.ButtonGroup_2);
            app.Button_8.Text = '片段';
            app.Label = uilabel(app.Panel);
            app.Label.Text = '每拍持续时间';
            app.Slider = uislider(app.Panel);
            app.Slider.Limits = [0 1];
            app.Slider.MajorTicks = [0 0.25 0.5 0.75 1];
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = '(临近取1/4, 1/2, 3/4或1)';
            app.ButtonGroup_3 = uibuttongroup(app.Panel);
            app.ButtonGroup_3.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_3SelectionChanged, true);
            app.ButtonGroup_3.Title = '包络类型';
            app.Button_16 = uiradiobutton(app.ButtonGroup_3);
            app.Button_16.Text = '无';
            app.Button_16.Value = true;
            app.Button_17 = uiradiobutton(app.ButtonGroup_3);
            app.Button_17.Text = '线性';
            app.Button_18 = uiradiobutton(app.ButtonGroup_3);
            app.Button_18.Text = '指数';
            app.Button_19 = uiradiobutton(app.ButtonGroup_3);
            app.Button_19.Text = '吉他';
            app.Button_6 = uibutton(app.Tab, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '停止播放';
            app.Image = uiimage(app.Tab);
            app.Image.ImageSource = 'DFH.png';
            app.Label_2 = uilabel(app.Tab);
            app.Label_2.Text = '《东方红》简谱';
            app.Tab_4 = uitab(app.TabGroup);
            app.Tab_4.Title = '音乐合成——自选曲目';
            app.Label_4 = uilabel(app.Tab_4);
            app.Label_4.Text = {'《夜空中最亮的星》'; '——逃跑计划乐队'};
            app.Image2 = uiimage(app.Tab_4);
            app.Image2.ImageSource = 'earth.png';
            app.Button_9 = uibutton(app.Tab_4, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '播放';
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '真实信号处理';
            app.UIAxes2 = uiaxes(app.Tab_2);
            title(app.UIAxes2, '真实吉他音')
            xlabel(app.UIAxes2, 't(8kHz Sampled)')
            ylabel(app.UIAxes2, 'realwave')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2_2 = uiaxes(app.Tab_2);
            title(app.UIAxes2_2, '待处理的吉他音')
            xlabel(app.UIAxes2_2, 't(8kHz Sampled)')
            ylabel(app.UIAxes2_2, 'wave2proc')
            zlabel(app.UIAxes2_2, 'Z')
            app.UIAxes2_3 = uiaxes(app.Tab_2);
            title(app.UIAxes2_3, '处理后的吉他音')
            xlabel(app.UIAxes2_3, 't(8kHz Sampled)')
            ylabel(app.UIAxes2_3, 'waveproc')
            zlabel(app.UIAxes2_3, 'Z')
            app.UIAxes4 = uiaxes(app.Tab_2);
            title(app.UIAxes4, '频谱结构')
            xlabel(app.UIAxes4, 'f')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes4_2 = uiaxes(app.Tab_2);
            title(app.UIAxes4_2, '谐波强度')
            xlabel(app.UIAxes4_2, 'a_i')
            ylabel(app.UIAxes4_2, 'a_i/a_1')
            zlabel(app.UIAxes4_2, 'Z')
            app.Label_5 = uilabel(app.Tab_2);
            app.Label_5.Text = '吉他小曲';
            app.Button_10 = uibutton(app.Tab_2, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Text = '播放一下';
            app.Button_11 = uibutton(app.Tab_2, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Button_11.Text = '停止播放';
            app.Label_6 = uilabel(app.Tab_2);
            app.Label_6.Text = '1.音乐信号处理';
            app.Button_12 = uibutton(app.Tab_2, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @Button_12Pushed, true);
            app.Button_12.Text = '处理！';
            app.Label_7 = uilabel(app.Tab_2);
            app.Label_7.Text = '2.分析频谱';
            app.Button_13 = uibutton(app.Tab_2, 'push');
            app.Button_13.ButtonPushedFcn = createCallbackFcn(app, @Button_13Pushed, true);
            app.Button_13.Text = '分析！';
            app.Label_8 = uilabel(app.Tab_2);
            app.Label_8.Text = '3.分析音调&节拍';
            app.Button_14 = uibutton(app.Tab_2, 'push');
            app.Button_14.ButtonPushedFcn = createCallbackFcn(app, @Button_14Pushed, true);
            app.Button_14.Text = '分析！';
            app.UITable = uitable(app.Tab_2);
            app.UITable.ColumnName = {'1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'; '11'; '12'; '13'; '14'; '15'; '16'; '17'; '18'; '19'; '20'; '21'; '22'; '23'; '24'; '25'; '26'; '27'; '28'; '29'; '30'; '31'; '32'; '33'; '34'; '35'};
            app.UITable.RowName = {'频率/Hz;'; '时长/s;'; '音调(ASCII）'};
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = '吉他模拟';
            app.Button_15 = uibutton(app.Tab_3, 'push');
            app.Button_15.ButtonPushedFcn = createCallbackFcn(app, @Button_15Pushed, true);
            app.Button_15.Text = '播放';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end